classdef SetThre1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CancelButton                 matlab.ui.control.Button
        ConfirmExitButton            matlab.ui.control.Button
        Default2obsmLabel            matlab.ui.control.Label
        Default500ppmLabel           matlab.ui.control.Label
        Default54Label               matlab.ui.control.Label
        ThresholdValueSettingsLabel  matlab.ui.control.Label
        VisibilityobsmSpinner        matlab.ui.control.Spinner
        VisibilityobsmSpinnerLabel   matlab.ui.control.Label
        COConcentrationppmSpinner    matlab.ui.control.Spinner
        COconcentrationppmLabel      matlab.ui.control.Label
        TemperatureSpinner           matlab.ui.control.Spinner
        TemperatureSpinnerLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
       callingapp % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DL1_1)
          app.callingapp=DL1_1;

        end

        % Button pushed function: ConfirmExitButton
        function ConfirmExitButtonPushed(app, event)

         % export threshold settings to DL1_1
         importTemp(app.callingapp,app.TemperatureSpinner.Value);
         importCO(app.callingapp,app.COConcentrationppmSpinner.Value);
         importVis(app.callingapp,app.VisibilityobsmSpinner.Value);

         delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TemperatureSpinnerLabel = uilabel(app.UIFigure);
            app.TemperatureSpinnerLabel.Text = 'Temperature (℃)';
            app.TemperatureSpinner = uispinner(app.UIFigure);
            app.TemperatureSpinner.Limits = [-20 100];
            app.TemperatureSpinner.Value = 54;
            app.COconcentrationppmLabel = uilabel(app.UIFigure);
            app.COconcentrationppmLabel.Text = 'CO Concentration (ppm)';
            app.COConcentrationppmSpinner = uispinner(app.UIFigure);
            app.COConcentrationppmSpinner.Step = 100;
            app.COConcentrationppmSpinner.Limits = [0 Inf];
            app.COConcentrationppmSpinner.Value = 500;
            app.VisibilityobsmSpinnerLabel = uilabel(app.UIFigure);
            app.VisibilityobsmSpinnerLabel.Text = 'Visibility (obs/m)';
            app.VisibilityobsmSpinner = uispinner(app.UIFigure);
            app.VisibilityobsmSpinner.Step = 0.2;
            app.VisibilityobsmSpinner.Limits = [0 Inf];
            app.VisibilityobsmSpinner.Value = 2;
            app.ThresholdValueSettingsLabel = uilabel(app.UIFigure);
            app.ThresholdValueSettingsLabel.Text = 'Threshold Value Settings';
            app.Default54Label = uilabel(app.UIFigure);
            app.Default54Label.Text = 'Default: 54℃';
            app.Default500ppmLabel = uilabel(app.UIFigure);
            app.Default500ppmLabel.Text = 'Default: 500ppm';
            app.Default2obsmLabel = uilabel(app.UIFigure);
            app.Default2obsmLabel.Text = 'Default: 2obs/m';
            app.ConfirmExitButton = uibutton(app.UIFigure, 'push');
            app.ConfirmExitButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmExitButtonPushed, true);
            app.ConfirmExitButton.Text = 'Confirm/Exit';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetThre1_1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end